classdef provaapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BBSToolbox               matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SceltadeiparametriPanel  matlab.ui.container.Panel
        AcquisizionepuntidicontrolloPanel  matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        DatastieraButton         matlab.ui.control.Button
        EliminapuntoButton       matlab.ui.control.Button
        DamouseButton            matlab.ui.control.Button
        DafileButton             matlab.ui.control.Button
        ConimmagineButton        matlab.ui.control.Button
        SceltadellacurvaedelmetodoPanel  matlab.ui.container.Panel
        Switch                   matlab.ui.control.Switch
        ButtonGroup              matlab.ui.container.ButtonGroup
        CustomScriptButton       matlab.ui.control.RadioButton
        MATLABRoutinesButton     matlab.ui.control.RadioButton
        GradocurvaLabel          matlab.ui.control.Label
        gradocurva               matlab.ui.control.Spinner
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        PlotButton               matlab.ui.control.Button
        PuliscigraficoButton     matlab.ui.control.Button
        CurvediBezierBSplineToolboxLabel  matlab.ui.control.Label
        ConsoleTextAreaLabel     matlab.ui.control.Label
        ConsoleTextArea          matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        selectedRows; 
        %Righe selezionate nella Callback CellSelection come v.condivisa
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BBSToolbox.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {568, 568};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {310, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DatastieraButton
        function DatastieraButtonPushed(app, event)
            newData = [0 0];
            app.UITable.Data = [app.UITable.Data;newData];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if(app.Switch.Value == "B-Spline" && app.CustomScriptButton.Value == 1)
                k = get(app.gradocurva,'Value');
            elseif (app.Switch.Value == "Bezier") 
                k = 1;
                % grado curva di Bezier almeno pari a 1 per non degenerare
                % nel singolo punto (almeno 2 punti in input).
                % In generale il grado dipende dal numero di punti di controllo
            else 
                k = 3;
            end
            
            puntidicontrollo = get(app.UITable,'Data');
            
            if (~isempty(puntidicontrollo) && sum(sum(isnan(puntidicontrollo)))==0) 
                x = puntidicontrollo(:,1)';
                y = puntidicontrollo(:,2)';
            else 
                uialert(app.BBSToolbox,strcat('Inserisci almeno',32, num2str(k+1), [' punti ' ...
                    'e assicurati che tutti i punti inseriti siano validi']),'Errore');
                %32 è il codice ASCII dello spazio
                return;
            end
            
            if(max(size(x))<=k)
                uialert(app.BBSToolbox,strcat('Inserisci almeno',32, num2str(k+1), ' punti'),'Errore');
                %32 è il codice ASCII dello spazio
                return;
            end
            
            switch app.Switch.Value
                case 'Bezier'
                    if(app.CustomScriptButton.Value)
                        tempo = Bezier_custom(x,y,app);
                        messaggio = "Tempo di esecuzione per " + "Bézier Custom con " + max(size(x)) + " punti: " + tempo + " s";
                    else
                        tempo = Bezier_MATLAB(x,y,app);
                        messaggio = "Tempo di esecuzione per " + "Bézier MATLAB con " + max(size(x)) + " punti: " + tempo + " s";
                    end
                case 'B-Spline'
                    if(app.CustomScriptButton.Value)
                        tempo = B_spline_custom(x,y,k,app);
                        messaggio = "Tempo di esecuzione per " + "B-Spline Custom con " + max(size(x)) + " punti: " + tempo + " s";
                    else
                        tempo = B_spline_MATLAB(x,y,app);
                        messaggio = "Tempo di esecuzione per " + "B-Spline MATLAB con " + max(size(x)) + " punti: " + tempo + " s";
                    end
            end
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value(:)',messaggio];
            
        end

        % Button pushed function: PuliscigraficoButton
        function PuliscigraficoButtonPushed(app, event)
            reset(app.UIAxes);
            cla(app.UIAxes, 'reset');
            title(app.UIAxes, 'Grafico');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            valueSwitch = app.Switch.Value;
            valueRadio = app.CustomScriptButton.Value;
            if(valueSwitch == "Bezier")
                app.gradocurva.Enable = 'off';
            elseif (valueSwitch =="B-Spline" && valueRadio==1)
                app.gradocurva.Enable = 'on';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            valueRadio = app.MATLABRoutinesButton.Value;
            valueSwitch = app.Switch.Value;
            if(valueRadio == 1)
                app.gradocurva.Enable = 'off';
            elseif (valueRadio == 0 && valueSwitch == "B-Spline")
                app.gradocurva.Enable = 'on';
            end
        end

        % Button pushed function: DamouseButton
        function DamouseButtonPushed(app, event)
           f=figure('NumberTitle', 'off', 'Name', 'Inserimento interattivo punti di controllo');
           while(size(findobj(f))>0)
               try
                   [x,y] = ginput(1);
                   figure(1); h=plot(x,y,'ko'); hold on; axis([0 1 0 1]);
                   set(h,'markerfacecolor',get(h,'Color'));
                   newData = [x y];
                   app.UITable.Data = [app.UITable.Data;newData];
               catch
               end
           end
        end

        % Button pushed function: EliminapuntoButton
        function EliminapuntoButtonPushed(app, event)
            try
                gridData = get(app.UITable, 'Data');
                gridData (app.selectedRows, :) = [];
                set(app.UITable, 'Data', gridData);
            catch
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedRows = event.Indices(:,1);
        end

        % Button pushed function: DafileButton
        function DafileButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.txt');
                newData = importdata(strcat(path,file));
                [~,n]=size(newData);
                if(n==2)
                    app.UITable.Data = [app.UITable.Data;newData];
                else 
                    uialert(app.BBSToolbox,'Formato del file non corretto','Errore');
                end
            catch
                uialert(app.BBSToolbox,'Impossibile aprire il file','Errore');
            end
        end

        % Button pushed function: ConimmagineButton
        function ConimmagineButtonPushed(app, event)
           try
               f=figure('NumberTitle', 'off', 'Name', 'Inserimento interattivo punti di controllo con immagine');
               imshow(flipud(imread('chitarra.jpg'))); hold on; axis on;
               set(gca,'Ydir','normal');
           catch
               close all;
               uialert(app.BBSToolbox,"Impossibile trovare chitarra.jpg nella cartella dell'applicazione","Errore");
               return;
           end
           
           while(size(findobj(f))>0)
               try
                   [x,y] = ginput(1); 
                   h=plot(x,y,'ko');
                   set(h,'markerfacecolor',get(h,'Color'));
                   newData = [x y];
                   app.UITable.Data = [app.UITable.Data;newData];
               catch
               end
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BBSToolbox = uifigure('Visible', 'off');
            app.BBSToolbox.Name = 'BBSToolbox';
            app.BBSToolbox.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BBSToolbox);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SceltadeiparametriPanel = uipanel(app.LeftPanel);
            app.SceltadeiparametriPanel.Title = 'Scelta dei parametri';
            app.AcquisizionepuntidicontrolloPanel = uipanel(app.SceltadeiparametriPanel);
            app.AcquisizionepuntidicontrolloPanel.Title = 'Acquisizione punti di controllo';
            app.UITable = uitable(app.AcquisizionepuntidicontrolloPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Tooltip = {'Tabella dei punti di controllo'};
            app.DatastieraButton = uibutton(app.AcquisizionepuntidicontrolloPanel, 'push');
            app.DatastieraButton.ButtonPushedFcn = createCallbackFcn(app, @DatastieraButtonPushed, true);
            app.DatastieraButton.Text = 'Da tastiera';
            app.EliminapuntoButton = uibutton(app.AcquisizionepuntidicontrolloPanel, 'push');
            app.EliminapuntoButton.ButtonPushedFcn = createCallbackFcn(app, @EliminapuntoButtonPushed, true);
            app.EliminapuntoButton.Text = 'Elimina punto';
            app.DamouseButton = uibutton(app.AcquisizionepuntidicontrolloPanel, 'push');
            app.DamouseButton.ButtonPushedFcn = createCallbackFcn(app, @DamouseButtonPushed, true);
            app.DamouseButton.Text = 'Da mouse';
            app.DafileButton = uibutton(app.AcquisizionepuntidicontrolloPanel, 'push');
            app.DafileButton.ButtonPushedFcn = createCallbackFcn(app, @DafileButtonPushed, true);
            app.DafileButton.Text = 'Da file';
            app.ConimmagineButton = uibutton(app.AcquisizionepuntidicontrolloPanel, 'push');
            app.ConimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @ConimmagineButtonPushed, true);
            app.ConimmagineButton.Text = 'Con immagine';
            app.SceltadellacurvaedelmetodoPanel = uipanel(app.SceltadeiparametriPanel);
            app.SceltadellacurvaedelmetodoPanel.Title = 'Scelta della curva e del metodo';
            app.Switch = uiswitch(app.SceltadellacurvaedelmetodoPanel, 'slider');
            app.Switch.Items = {'Bezier', 'B-Spline'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'B-Spline';
            app.ButtonGroup = uibuttongroup(app.SceltadellacurvaedelmetodoPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Tooltip = {'Scelta dell''algoritmo da utilizzare'};
            app.CustomScriptButton = uiradiobutton(app.ButtonGroup);
            app.CustomScriptButton.Text = 'Custom Script';
            app.CustomScriptButton.Value = true;
            app.MATLABRoutinesButton = uiradiobutton(app.ButtonGroup);
            app.MATLABRoutinesButton.Text = 'MATLAB Routines';
            app.GradocurvaLabel = uilabel(app.SceltadellacurvaedelmetodoPanel);
            app.GradocurvaLabel.Text = 'Grado curva';
            app.gradocurva = uispinner(app.SceltadellacurvaedelmetodoPanel);
            app.gradocurva.Limits = [1 3];
            app.gradocurva.RoundFractionalValues = 'on';
            app.gradocurva.Tooltip = {'Incremento del numero di nodi per aumentare l''accuratezza della curva'};
            app.gradocurva.Value = 3;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PuliscigraficoButton = uibutton(app.RightPanel, 'push');
            app.PuliscigraficoButton.ButtonPushedFcn = createCallbackFcn(app, @PuliscigraficoButtonPushed, true);
            app.PuliscigraficoButton.Text = 'Pulisci grafico';
            app.CurvediBezierBSplineToolboxLabel = uilabel(app.RightPanel);
            app.CurvediBezierBSplineToolboxLabel.Text = 'Curve di Bezier & B-Spline Toolbox';
            app.ConsoleTextAreaLabel = uilabel(app.RightPanel);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.RightPanel);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Value = {' '};
            % Show the figure after all components are created
            app.BBSToolbox.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = provaapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BBSToolbox)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BBSToolbox)
        end
    end
end